classdef jpeg_compress < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        pleloadedimageDropDownLabel  matlab.ui.control.Label
        pleloadedimageDropDown       matlab.ui.control.DropDown
        CompressButton               matlab.ui.control.Button
        QualintySpinnerLabel         matlab.ui.control.Label
        QualintySpinner              matlab.ui.control.Spinner
        useLabel                     matlab.ui.control.Label
        orLabel                      matlab.ui.control.Label
        selectimageButton            matlab.ui.control.Button
        Image                        matlab.ui.control.Image
        DecodedImage                 matlab.ui.control.Image
        OriginalImageLabel           matlab.ui.control.Label
        DecodedImageLabel            matlab.ui.control.Label
        ssimLabel                    matlab.ui.control.Label
        bppLabel                     matlab.ui.control.Label
        ssimVal                      matlab.ui.control.Label
        bppVal                       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            value = app.pleloadedimageDropDown.Value;
            ImageFile = imread(value);
            app.Image.ImageSource = ImageFile;
        end

        % Value changed function: pleloadedimageDropDown
        function pleloadedimageDropDownValueChanged(app, event)
            value = app.pleloadedimageDropDown.Value;
            ImageFile = imread(value);
            app.Image.ImageSource = ImageFile;
        end

        % Button pushed function: CompressButton
        function CompressButtonPushed(app, event)
            image = app.Image.ImageSource;
            [imgHeight, imgWidth, ~] = size(image);
            
            if ((mod(imgHeight, 8) > 0) || mod(imgWidth, 8) > 0)
                image = resizeImageTo8(image);
            end
            
            q = app.QualintySpinner.Value;
            
            [bitStr, imgDimensions] = jpegEncoder(image, q);
            
            decodedImage = jpegDecoder(bitStr, imgDimensions, q);
            
            bpp = jpegCodedImageSize(bitStr, imgDimensions);
            app.bppVal.Text = num2str(bpp);
            ssimValue = ssim(image, decodedImage);
            app.ssimVal.Text = num2str(ssimValue);
            
            app.DecodedImage.ImageSource = decodedImage;
        end

        % Button pushed function: selectimageButton
        function selectimageButtonPushed(app, event)
            [filename,filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.oct'}, 'Select File to Open');
            fullname = [filepath, filename];
            ImageFile = imread(fullname);
            app.Image.ImageSource = ImageFile;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.pleloadedimageDropDownLabel = uilabel(app.UIFigure);
            app.pleloadedimageDropDownLabel.Text = 'pleloaded image';
            app.pleloadedimageDropDown = uidropdown(app.UIFigure);
            app.pleloadedimageDropDown.Items = {'peppers.png', 'pears.png'};
            app.pleloadedimageDropDown.ValueChangedFcn = createCallbackFcn(app, @pleloadedimageDropDownValueChanged, true);
            app.pleloadedimageDropDown.Value = 'peppers.png';
            app.CompressButton = uibutton(app.UIFigure, 'push');
            app.CompressButton.ButtonPushedFcn = createCallbackFcn(app, @CompressButtonPushed, true);
            app.CompressButton.Text = 'Compress ';
            app.QualintySpinnerLabel = uilabel(app.UIFigure);
            app.QualintySpinnerLabel.Text = 'Qualinty';
            app.QualintySpinner = uispinner(app.UIFigure);
            app.QualintySpinner.Limits = [1 100];
            app.QualintySpinner.Value = 80;
            app.useLabel = uilabel(app.UIFigure);
            app.useLabel.Text = 'use';
            app.orLabel = uilabel(app.UIFigure);
            app.orLabel.Text = 'or';
            app.selectimageButton = uibutton(app.UIFigure, 'push');
            app.selectimageButton.ButtonPushedFcn = createCallbackFcn(app, @selectimageButtonPushed, true);
            app.selectimageButton.Text = 'select image';
            app.Image = uiimage(app.UIFigure);
            app.DecodedImage = uiimage(app.UIFigure);
            app.OriginalImageLabel = uilabel(app.UIFigure);
            app.OriginalImageLabel.Text = 'Original Image';
            app.DecodedImageLabel = uilabel(app.UIFigure);
            app.DecodedImageLabel.Text = 'Decoded Image';
            app.ssimLabel = uilabel(app.UIFigure);
            app.ssimLabel.Text = 'ssim:';
            app.bppLabel = uilabel(app.UIFigure);
            app.bppLabel.Text = 'bpp:';
            app.ssimVal = uilabel(app.UIFigure);
            app.ssimVal.Text = '';
            app.bppVal = uilabel(app.UIFigure);
            app.bppVal.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = jpeg_compress

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end